classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        Soal1Tab                     matlab.ui.container.Tab
        srcEditField                 matlab.ui.control.EditField
        PilihGambarButton            matlab.ui.control.Button
        srcEditFieldLabel            matlab.ui.control.Label
        UIAxes_Image                 matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        Soal2Tab                     matlab.ui.container.Tab
        bEditFieldLabel              matlab.ui.control.Label
        cEditFieldLabel              matlab.ui.control.Label
        gammaEditFieldLabel          matlab.ui.control.Label
        srcEditField2                matlab.ui.control.EditField
        PilihGambarButton2           matlab.ui.control.Button
        OperationDropDown            matlab.ui.control.DropDown
        ParameterLabel               matlab.ui.control.Label
        aEditField                   matlab.ui.control.NumericEditField
        aEditFieldLabel              matlab.ui.control.Label
        OperationDropDownLabel       matlab.ui.control.Label
        bEditField                   matlab.ui.control.NumericEditField
        cEditField                   matlab.ui.control.NumericEditField
        gammaEditField               matlab.ui.control.NumericEditField
        srcEditField_2Label_2        matlab.ui.control.Label
        UIAxes2_ImageIn              matlab.ui.control.UIAxes
        UIAxes2_ImageOut             matlab.ui.control.UIAxes
        hist2_in                     matlab.ui.control.UIAxes
        hist2_out                    matlab.ui.control.UIAxes
        Soal3Tab                     matlab.ui.container.Tab
        srcEditField3                matlab.ui.control.EditField
        srcEditField_2Label          matlab.ui.control.Label
        PilihGambarButton3           matlab.ui.control.Button
        UIAxes3_ImageIn              matlab.ui.control.UIAxes
        UIAxes3_ImageOut             matlab.ui.control.UIAxes
        UIAxes3_ImageOut2            matlab.ui.control.UIAxes
        hist3_in                     matlab.ui.control.UIAxes
        hist3_out                    matlab.ui.control.UIAxes
        hist3_matOut                 matlab.ui.control.UIAxes
        Soal4Tab                     matlab.ui.container.Tab
        InputSrcEditField4           matlab.ui.control.EditField
        InputPilihGambarButton4      matlab.ui.control.Button
        ReferenceSrcEditField4       matlab.ui.control.EditField
        srcEditField_2Label_4        matlab.ui.control.Label
        ReferencePilihGambarButton4  matlab.ui.control.Button
        srcEditField_2Label_3        matlab.ui.control.Label
        UIAxes4_ImageIn              matlab.ui.control.UIAxes
        UIAxes4_ImageReference       matlab.ui.control.UIAxes
        UIAxes4_ImageOut             matlab.ui.control.UIAxes
        hist4_in                     matlab.ui.control.UIAxes
        hist4_reference              matlab.ui.control.UIAxes
        hist4_out                    matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function plot_histogram(~, ax, img)
            % PLOTHISTOGRAM Menampilkan histogram img pada suatu UiAxis
        
            cla(ax);
         
            [counts, binLocations] = image_histogram(img);

            % Use bar plot to display the histogram in the specified axes
            b = bar(ax, binLocations, counts); 
        
            if (size(counts,1) == 3)
                b(1).FaceColor = [1, 0, 0];
                b(2).FaceColor = [0, 1, 0];
                b(3).FaceColor = [0, 0, 1];
            end
            xlabel(ax,'Pixel Intensity');
            ylabel(ax, 'Count');
            title(ax, 'Histogram');
            legend(b, 'Red', 'Green', 'Blue');
        end

        function [img, fullPath] = browseImage(app)
           [file, path] = uigetfile('*.png;*.jpg;*.bmp;*.tif', 'Pilih Gambar');

            
            if isequal(file, 0)  % User clicked cancel
                img = [];        % Return empty image
                fullPath = '';   % Return empty path
            else
                fullPath = fullfile(path, file);  % Concatenate path and file
                img = imread(fullPath);            % Read the image file
            end

            drawnow;
            figure(app.UIFigure)

        end
        
        function transformed_img = section2_get_transformation(app, img)
            operation_value = app.OperationDropDown.Value;
            switch operation_value
                case 'Image Brightening'
                    a_value = app.aEditField.Value;
                    b_value = app.bEditField.Value;

                    transformed_img = image_brightening(img, a_value, b_value);
                case 'Image Negatives'
                    transformed_img = image_negatives(img);
                case 'Log Transformation'
                    c_value = app.cEditField.Value;

                    transformed_img = log_transformation(img, c_value);
                case 'Exponential Transformation'
                    c_value = app.cEditField.Value;
                    gamma_value = app.gammaEditField.Value;

                    transformed_img = exponential_transformation(img, c_value, gamma_value);
                case 'Contrast Stretching'
                    transformed_img = contrast_stretching(img);
            end
        end

        function section2_apply_transformation(app)        
            if app.srcEditField2.Value == ""
                cla(app.UIAxes2_ImageOut, 'reset');
            else
                img = imread(app.srcEditField2.Value);
                img = section2_get_transformation(app, img);
                imshow(img, 'Parent', app.UIAxes2_ImageOut);
                plot_histogram(app, app.hist2_out, img);
            end
        end

        function section4_check_inputs(app)    
            if app.InputSrcEditField4.Value ~= "" && app.ReferenceSrcEditField4.Value ~= ""
                input_img = imread(app.InputSrcEditField4.Value);
                reference_img = imread(app.ReferenceSrcEditField4.Value);
                output_img = histogram_specification(input_img, reference_img);
                imshow(output_img, 'Parent', app.UIAxes4_ImageOut);
                plot_histogram(app, app.hist4_out, output_img);
            else
                cla(app.UIAxes4_ImageOut, 'reset');
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PilihGambarButton
        function PilihGambarButtonPushed(app, event)
            [img, fullPath] = browseImage(app);
            app.srcEditField.Value = fullPath;
            imshow(img, 'Parent', app.UIAxes_Image);
            
            plot_histogram(app, app.UIAxes, img);
        end

        % Button pushed function: PilihGambarButton2
        function PilihGambarButton2Pushed(app, event)
            [img, fullPath] = browseImage(app);
            app.srcEditField2.Value = fullPath;
            imshow(img, 'Parent', app.UIAxes2_ImageIn);
            plot_histogram(app, app.hist2_in, img);
            section2_apply_transformation(app);
        end

        % Value changed function: OperationDropDown
        function OperationDropDownValueChanged(app, event)
            value = app.OperationDropDown.Value;
            
            app.ParameterLabel.Visible = 'off';
            app.aEditField.Visible = 'off';
            app.aEditFieldLabel.Visible = 'off';
            app.bEditField.Visible = 'off';
            app.bEditFieldLabel.Visible = 'off';
            app.cEditField.Visible = 'off';
            app.cEditFieldLabel.Visible = 'off';
            app.gammaEditField.Visible = 'off';
            app.gammaEditFieldLabel.Visible = 'off';

            switch value
                case 'Image Brightening'
                    app.ParameterLabel.Visible = 'on';
                    app.aEditField.Visible = 'on';
                    app.aEditFieldLabel.Visible = 'on';
                    app.bEditField.Visible = 'on';
                    app.bEditFieldLabel.Visible = 'on';
                    
                    app.aEditField.Value = 1;
                    app.bEditField.Value = 0;
                case 'Image Negatives'

                case 'Log Transformation'
                    app.ParameterLabel.Visible = 'on';
                    app.cEditField.Visible = 'on';
                    app.cEditFieldLabel.Visible = 'on';

                    app.cEditField.Value = 1;
                case 'Exponential Transformation'
                    app.ParameterLabel.Visible = 'on';
                    app.cEditField.Visible = 'on';
                    app.cEditFieldLabel.Visible = 'on';
                    app.gammaEditField.Visible = 'on';
                    app.gammaEditFieldLabel.Visible = 'on';

                    app.cEditField.Value = 1;
                    app.gammaEditField.Value = 1;
                case 'Contrast Stretching'

            end

            section2_apply_transformation(app);
        end

        % Value changed function: aEditField, bEditField, cEditField, 
        % ...and 1 other component
        function Section2EditFieldValueChanged(app, event)
            section2_apply_transformation(app);
        end

        % Button pushed function: PilihGambarButton3
        function PilihGambarButton3Pushed(app, event)
            [img, fullPath] = browseImage(app);
            app.srcEditField3.Value = fullPath;
            out = hist_equalization(img);
            matOut = histeq(img);
            cla(app.UIAxes3_ImageIn, 'reset');
            cla(app.UIAxes3_ImageOut, 'reset');
            cla(app.UIAxes3_ImageOut2, 'reset');
            imshow(img, 'Parent', app.UIAxes3_ImageIn);
            imshow(out, 'Parent', app.UIAxes3_ImageOut);
            imshow(matOut, 'Parent', app.UIAxes3_ImageOut2);
            plot_histogram(app, app.hist3_in, img);
            plot_histogram(app, app.hist3_out, out);
            plot_histogram(app, app.hist3_matOut, matOut);
        end

        % Button pushed function: InputPilihGambarButton4
        function InputPilihGambarButton4Pushed(app, event)
            [img, fullPath] = browseImage(app);
            app.InputSrcEditField4.Value = fullPath;
            imshow(img, 'Parent', app.UIAxes4_ImageIn);
            plot_histogram(app, app.hist4_in, img);
            section4_check_inputs(app);
        end

        % Button pushed function: ReferencePilihGambarButton4
        function ReferencePilihGambarButton4Pushed(app, event)
            [img, fullPath] = browseImage(app);
            app.ReferenceSrcEditField4.Value = fullPath;
            imshow(img, 'Parent', app.UIAxes4_ImageReference);
            plot_histogram(app, app.hist4_reference, img);
            section4_check_inputs(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Soal1Tab = uitab(app.TabGroup);
            app.Soal1Tab.Title = 'Soal 1';
            app.UIAxes = uiaxes(app.Soal1Tab);
            title(app.UIAxes, 'Histogram')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_Image = uiaxes(app.Soal1Tab);
            title(app.UIAxes_Image, 'Gambar')
            app.UIAxes_Image.XTick = [0 0.5];
            app.UIAxes_Image.XTickLabel = '';
            app.UIAxes_Image.YTick = [];
            app.srcEditFieldLabel = uilabel(app.Soal1Tab);
            app.srcEditFieldLabel.Text = 'src';
            app.PilihGambarButton = uibutton(app.Soal1Tab, 'push');
            app.PilihGambarButton.ButtonPushedFcn = createCallbackFcn(app, @PilihGambarButtonPushed, true);
            app.PilihGambarButton.Text = 'Pilih Gambar';
            app.srcEditField = uieditfield(app.Soal1Tab, 'text');
            app.srcEditField.Editable = 'off';
            app.Soal2Tab = uitab(app.TabGroup);
            app.Soal2Tab.Title = 'Soal 2';
            app.hist2_out = uiaxes(app.Soal2Tab);
            title(app.hist2_out, 'Histogram')
            app.hist2_in = uiaxes(app.Soal2Tab);
            title(app.hist2_in, 'Histogram')
            app.UIAxes2_ImageOut = uiaxes(app.Soal2Tab);
            title(app.UIAxes2_ImageOut, 'Output')
            app.UIAxes2_ImageOut.XTick = [0 0.5];
            app.UIAxes2_ImageOut.XTickLabel = '';
            app.UIAxes2_ImageOut.YTick = [];
            app.UIAxes2_ImageIn = uiaxes(app.Soal2Tab);
            title(app.UIAxes2_ImageIn, 'Input')
            app.UIAxes2_ImageIn.XTick = [];
            app.UIAxes2_ImageIn.YTick = [];
            app.srcEditField_2Label_2 = uilabel(app.Soal2Tab);
            app.srcEditField_2Label_2.Text = 'src';
            app.gammaEditField = uieditfield(app.Soal2Tab, 'numeric');
            app.gammaEditField.ValueChangedFcn = createCallbackFcn(app, @Section2EditFieldValueChanged, true);
            app.gammaEditField.Visible = 'off';
            app.cEditField = uieditfield(app.Soal2Tab, 'numeric');
            app.cEditField.ValueChangedFcn = createCallbackFcn(app, @Section2EditFieldValueChanged, true);
            app.cEditField.Visible = 'off';
            app.cEditField.Value = 1;
            app.bEditField = uieditfield(app.Soal2Tab, 'numeric');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @Section2EditFieldValueChanged, true);
            app.OperationDropDownLabel = uilabel(app.Soal2Tab);
            app.OperationDropDownLabel.Text = 'Operation';
            app.aEditFieldLabel = uilabel(app.Soal2Tab);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.Soal2Tab, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @Section2EditFieldValueChanged, true);
            app.aEditField.Value = 1;
            app.ParameterLabel = uilabel(app.Soal2Tab);
            app.ParameterLabel.Text = 'Parameter';
            app.OperationDropDown = uidropdown(app.Soal2Tab);
            app.OperationDropDown.Items = {'Image Brightening', 'Image Negatives', 'Log Transformation', 'Exponential Transformation', 'Contrast Stretching'};
            app.OperationDropDown.ValueChangedFcn = createCallbackFcn(app, @OperationDropDownValueChanged, true);
            app.OperationDropDown.Value = 'Image Brightening';
            app.PilihGambarButton2 = uibutton(app.Soal2Tab, 'push');
            app.PilihGambarButton2.ButtonPushedFcn = createCallbackFcn(app, @PilihGambarButton2Pushed, true);
            app.PilihGambarButton2.Text = 'Pilih Gambar';
            app.srcEditField2 = uieditfield(app.Soal2Tab, 'text');
            app.srcEditField2.Editable = 'off';
            app.gammaEditFieldLabel = uilabel(app.Soal2Tab);
            app.gammaEditFieldLabel.Visible = 'off';
            app.gammaEditFieldLabel.Text = 'gamma';
            app.cEditFieldLabel = uilabel(app.Soal2Tab);
            app.cEditFieldLabel.Visible = 'off';
            app.cEditFieldLabel.Text = 'c';
            app.bEditFieldLabel = uilabel(app.Soal2Tab);
            app.bEditFieldLabel.Text = 'b';
            app.Soal3Tab = uitab(app.TabGroup);
            app.Soal3Tab.Title = 'Soal 3';
            app.hist3_matOut = uiaxes(app.Soal3Tab);
            title(app.hist3_matOut, 'Histogram')
            app.hist3_out = uiaxes(app.Soal3Tab);
            title(app.hist3_out, 'Histogram')
            app.hist3_in = uiaxes(app.Soal3Tab);
            title(app.hist3_in, 'Histogram')
            app.UIAxes3_ImageOut2 = uiaxes(app.Soal3Tab);
            title(app.UIAxes3_ImageOut2, 'Matlab Output')
            app.UIAxes3_ImageOut2.XTick = [];
            app.UIAxes3_ImageOut2.XTickLabel = '';
            app.UIAxes3_ImageOut2.YTick = [];
            app.UIAxes3_ImageOut = uiaxes(app.Soal3Tab);
            title(app.UIAxes3_ImageOut, 'Output')
            app.UIAxes3_ImageOut.XTick = [0 0.5];
            app.UIAxes3_ImageOut.XTickLabel = '';
            app.UIAxes3_ImageOut.YTick = [];
            app.UIAxes3_ImageIn = uiaxes(app.Soal3Tab);
            title(app.UIAxes3_ImageIn, 'Input')
            app.UIAxes3_ImageIn.XTick = [];
            app.UIAxes3_ImageIn.YTick = [];
            app.PilihGambarButton3 = uibutton(app.Soal3Tab, 'push');
            app.PilihGambarButton3.ButtonPushedFcn = createCallbackFcn(app, @PilihGambarButton3Pushed, true);
            app.PilihGambarButton3.Text = 'Pilih Gambar';
            app.srcEditField_2Label = uilabel(app.Soal3Tab);
            app.srcEditField_2Label.Text = 'src';
            app.srcEditField3 = uieditfield(app.Soal3Tab, 'text');
            app.srcEditField3.Editable = 'off';
            app.Soal4Tab = uitab(app.TabGroup);
            app.Soal4Tab.Title = 'Soal 4';
            app.hist4_out = uiaxes(app.Soal4Tab);
            title(app.hist4_out, 'Histogram')
            app.hist4_reference = uiaxes(app.Soal4Tab);
            title(app.hist4_reference, 'Histogram')
            app.hist4_in = uiaxes(app.Soal4Tab);
            title(app.hist4_in, 'Histogram')
            app.UIAxes4_ImageOut = uiaxes(app.Soal4Tab);
            title(app.UIAxes4_ImageOut, 'Output')
            app.UIAxes4_ImageOut.XTick = [];
            app.UIAxes4_ImageOut.XTickLabel = '';
            app.UIAxes4_ImageOut.YTick = [];
            app.UIAxes4_ImageReference = uiaxes(app.Soal4Tab);
            title(app.UIAxes4_ImageReference, 'Reference')
            app.UIAxes4_ImageReference.XTick = [0 0.5];
            app.UIAxes4_ImageReference.XTickLabel = '';
            app.UIAxes4_ImageReference.YTick = [];
            app.UIAxes4_ImageIn = uiaxes(app.Soal4Tab);
            title(app.UIAxes4_ImageIn, 'Input')
            app.UIAxes4_ImageIn.XTick = [];
            app.UIAxes4_ImageIn.YTick = [];
            app.srcEditField_2Label_3 = uilabel(app.Soal4Tab);
            app.srcEditField_2Label_3.Text = 'Input';
            app.ReferencePilihGambarButton4 = uibutton(app.Soal4Tab, 'push');
            app.ReferencePilihGambarButton4.ButtonPushedFcn = createCallbackFcn(app, @ReferencePilihGambarButton4Pushed, true);
            app.ReferencePilihGambarButton4.Text = 'Pilih Gambar';
            app.srcEditField_2Label_4 = uilabel(app.Soal4Tab);
            app.srcEditField_2Label_4.Text = 'Reference';
            app.ReferenceSrcEditField4 = uieditfield(app.Soal4Tab, 'text');
            app.ReferenceSrcEditField4.Editable = 'off';
            app.InputPilihGambarButton4 = uibutton(app.Soal4Tab, 'push');
            app.InputPilihGambarButton4.ButtonPushedFcn = createCallbackFcn(app, @InputPilihGambarButton4Pushed, true);
            app.InputPilihGambarButton4.Text = 'Pilih Gambar';
            app.InputSrcEditField4 = uieditfield(app.Soal4Tab, 'text');
            app.InputSrcEditField4.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end